classdef SLS_Oberflaechenrauheit_final_App_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProgrammMenu                matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        OpenInputFolderMenu         matlab.ui.container.Menu
        OpenOutputFolderMenu        matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        PreferencesMenu             matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        FastModeTab                 matlab.ui.container.Tab
        LoadImageButton             matlab.ui.control.Button
        PrepareButton               matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        CalculateButton             matlab.ui.control.Button
        icon_settings1              matlab.ui.control.Image
        icon_folder1                matlab.ui.control.Image
        icon_trash1                 matlab.ui.control.Image
        icon_calc1                  matlab.ui.control.Image
        icon_settings2              matlab.ui.control.Image
        icon_settings3              matlab.ui.control.Image
        icon_help                   matlab.ui.control.Image
        ScaleLabel                  matlab.ui.control.Label
        LButton_2                   matlab.ui.control.StateButton
        RButton                     matlab.ui.control.StateButton
        LengthScaleEditField        matlab.ui.control.NumericEditField
        DropDown                    matlab.ui.control.DropDown
        ExportButton                matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        SingleModeTab               matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        MaterialIslandsButton       matlab.ui.control.Button
        UndercutsButton             matlab.ui.control.Button
        PoresButton                 matlab.ui.control.Button
        RoughnessButton             matlab.ui.control.Button
        UndercutsbehindMaterialIslandsButton  matlab.ui.control.Button
        StartmanuallyLabel          matlab.ui.control.Label
        SettingsTab                 matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        ErosionTab                  matlab.ui.container.Tab
        SizeSpinnerLabel            matlab.ui.control.Label
        SizeSpinner                 matlab.ui.control.Spinner
        TypDropDownLabel            matlab.ui.control.Label
        TypDropDown                 matlab.ui.control.DropDown
        ImageTab                    matlab.ui.container.Tab
        ImageTypeDropDownLabel      matlab.ui.control.Label
        ImageTypeDropDown           matlab.ui.control.DropDown
        PlotDropDownLabel           matlab.ui.control.Label
        PlotDropDown                matlab.ui.control.DropDown
        ParametersTab               matlab.ui.container.Tab
        TabGroup3                   matlab.ui.container.TabGroup
        MatIslandsTab               matlab.ui.container.Tab
        UndercutsTab                matlab.ui.container.Tab
        PoresTab                    matlab.ui.container.Tab
        RoughnessTab                matlab.ui.container.Tab
        Label                       matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditFieldLabel  matlab.ui.control.Label
        NumberofsectionsalongpicturewidthEditField  matlab.ui.control.NumericEditField
        SpacebehindMatIslandsTab    matlab.ui.container.Tab
        PathsTab                    matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        SpeicherortLabel            matlab.ui.control.Label
        SpeicherortEditField        matlab.ui.control.EditField
        BrowseButton_3              matlab.ui.control.Button
        WorkingDirEditFieldLabel    matlab.ui.control.Label
        WorkingDirEditField         matlab.ui.control.EditField
        ApplyButton                 matlab.ui.control.Button
        icon_help_2                 matlab.ui.control.Image
        ResultsTab                  matlab.ui.container.Tab
        TabGroup3_2                 matlab.ui.container.TabGroup
        MatIslandsTab_2             matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        UndercutsTab_2              matlab.ui.container.Tab
        UIAxes3_2                   matlab.ui.control.UIAxes
        PoresTab_2                  matlab.ui.container.Tab
        UIAxes3_3                   matlab.ui.control.UIAxes
        RoughnessTab_2              matlab.ui.container.Tab
        UIAxes3_4                   matlab.ui.control.UIAxes
        SpacebehindMatIslandsTab_2  matlab.ui.container.Tab
        UIAxes3_5                   matlab.ui.control.UIAxes
        HeaderPhoto                 matlab.ui.control.Image
        FooterPhoto                 matlab.ui.control.Image
    end

    
    properties (Access = public)
        %% set global variables
        %% related to image
        BildPfad = 0;
        BildName = 0;
        BildNamePlusExtention = 0;
        imgBW = 0;
        imgBWnoScale = 0;
        imgEroded = 0;
        imgErod = 0; % ausgerichtetes Erodiertes Bild
        imgOriginal = 0;
        imgOrig = 0; % ausgerichtetes Originalbild
        overallResults = [];
        %         overallResults.Kennwert1 = [];
        %         overallResults.Kennwert2 = [];
        %         overallResults.Kennwert3 = [];
        %         overallResults.Kennwert4 = [];
        %         overallResults.Kennwert5 = [];
        doPlot = 0;
        captionFontSize = 14;
        
        %% surface
        realeKonturX = 0;
        realeKonturY = 0;
        aufKontur = 0;
        FlaecheHinterschneidung = 0;
        Pz_Wert = 0;
        %% scale
        MassstabLinksPosX = 0;
        MassstabLinksPosY = 0;
        MassstabRechtsPosX = 0;
        MassstabRechtsPosY = 0;
        MassstabWert = 1;
        MassstabEinheit = 'px';
        %%
        hfig = 0;
        scaleResolution = 0;
        ExcelKennwerte = 0;
        ExcelDaten = 0;
        AnzahlErzeugteDateien = 1;
        ErzeugteDateien = string(zeros(100,2));
        %% Colors
        gruen=[0.78 0.88 0.58];
        hellgruen=[0.73	0.88	0.48];
        dunkelrot=[0.64	0.08	0.18];
        hellrot=[0.93	0.41	0.50];
        grau=[0.50	0.50	0.50];
        schwarz=[0	0	0];
        rosa=[1.00	0.80	0.80];
        hintergrundwert;
        materialwert;
        schwellwert;
        imageWidth;
        imageHeight;
        erosionType;
        erosionValue;
        
        currentFolder;
        guiImagesPath;
        exportPath;
        functionPath;
        functionPath2017;
        imagePath;
        workingDirectory;
        savingDirectory;
        state;
        initialState;
    end
    
    methods (Access = private)
        
        function results = ButtonDown(app)
            get(app.hfig)
        end
    end
    
    methods (Access = public)


        function runAllQualityParametersWithGivenParameters(app)
            
            tic
            CalcQualityParam1(app);
            toc
            
            tic
            CalcQualityParam2(app);
            toc
            
            tic
            CalcQualityParam3(app);
            toc
            
            tic
            CalcQualityParam4(app);
            toc
            
            tic
            CalcQualityParam5(app);
            toc
            
            guidata(app.UIFigure, app);
            set(app.CalculateButton, 'BackgroundColor', 'green');
            
            if app.doPlot
                figure
                subplot(2,3,1)
                imshow(app.overallResults.Kennwert1.coloredLabels)
                title('1. Material Islands')
                subplot(2,3,2)
                imshow(app.overallResults.Kennwert2.coloredLabels)
                title('2. Undercuts')
                subplot(2,3,3)
                imshow(app.overallResults.Kennwert3.coloredLabels)
                title('3. Pores')
                subplot(2,3,4)
                imshow(app.overallResults.Kennwert4.coloredLabels)
                title('4. Roughness')
                subplot(2,3,5)
                imshow(app.overallResults.Kennwert5.coloredLabels)
                title('5. Undercuts behind Material Islands')
                imshow(app.overallResults.Kennwert3.coloredLabels, 'Parent', app.UIAxes3_3);
                imshow(app.overallResults.Kennwert1.coloredLabels, 'Parent', app.UIAxes3);
                imshow(app.overallResults.Kennwert2.coloredLabels, 'Parent', app.UIAxes3_2);
                imshow(app.overallResults.Kennwert4.coloredLabels, 'Parent', app.UIAxes3_4);
                imshow(app.overallResults.Kennwert5.coloredLabels, 'Parent', app.UIAxes3_5);
            end
        end
        
        function CalcQualityParam1(app)
            [results] = calculateSurfaceParameters(app, app.imgErod, 'matIslands', 'Materialinseln');
            % visualUpdateOfGUIAfterFirstParameter(handles)
            % defineKennwertAsCompleted(handles, results, '1')
            %% save results
            [app.overallResults.Kennwert1] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam2(app)
            %% find material islands and eliminate
            [originalImageNoMatIslands] = eliminateBlobsFromImage(app.imgEroded);
            %% find pores and eliminate
            [originalImageBothEliminated] = eliminateBlobsFromImage(~originalImageNoMatIslands);
            %% draw vertical lines around undercuts
            [thirdStepImage] = drawVerticalLinesForUndercuts(~originalImageBothEliminated);
            %% detect undercuts ( = detect pores)
            [results] = calculateSurfaceParameters(app, thirdStepImage, 'pores', 'Materialinseln');
            if app.doPlot
                figure
                subplot(2, 3, 1)
                imshow(app.imgEroded)
                title('1: Original SW-Image')
                subplot(2, 3, 2)
                imshow(originalImageNoMatIslands)
                title('2. Elimination of Material Islands')
                subplot(2, 3, 3)
                imshow(~originalImageBothEliminated)
                title('3. Elimination of Pores')
                subplot(2, 3, 4)
                imshow(thirdStepImage)
                title('4. Drawing vertical Lines')
                subplot(2, 3, 5)
                imshow(results.coloredLabels)
                title('5. Detected Undercuts')
            end
            %% save results
            [app.overallResults.Kennwert2] = results;
            guidata(app.UIFigure, app);
        end
        
        function app = CalcQualityParam3(app)
            [results] = calculateSurfaceParameters(app, app.imgEroded, 'pores', 'Materialinseln');
            if app.doPlot
                imshow(results.coloredLabels, 'Parent', app.UIAxes3_3);
            end
            [app.overallResults.Kennwert3] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam4(app)
            %% find material islands and eliminate
            [originalImageNoMatIslands] = eliminateBlobsFromImage(app.imgEroded);
            %% find pores and eliminate
            [originalImageBothEliminated] = eliminateBlobsFromImage(~originalImageNoMatIslands);
            %% draw vertical lines around undercuts
            [vertLinesForUndercuts] = drawVerticalLinesForUndercuts(~originalImageBothEliminated);
            %% fill out undercuts
            [undercutsEliminated] = eliminateBlobsFromImage(~vertLinesForUndercuts);
            %% detect hills and valleys
            [roughnessImage] = calcSurfaceRoughness(app, undercutsEliminated);
            %% find roughness areas
            [results] = calculateSurfaceParameters(app, roughnessImage, 'pores', 'Roughness Areas');
            [app.overallResults.Kennwert4] = results;
            guidata(app.UIFigure, app);
        end
        
        function CalcQualityParam5(app)
            %% find pores and eliminate
            [poresEliminated] = eliminateBlobsFromImage(~app.imgEroded);
            %% store matIslands data
            [resultsMatIslands] = calculateSurfaceParameters(app, ~poresEliminated, 'matIslands', 'Materialinseln');
            %% detect matIslands data and delete
            [matIslandsEliminated] = eliminateBlobsFromImage(~poresEliminated);
            %% draw vertical lines around undercuts
            [vertLinesForUndercuts] = drawVerticalLinesForUndercuts(matIslandsEliminated);
            %% fill out undercuts
            [undercutsEliminated] = eliminateBlobsFromImage(~vertLinesForUndercuts);
            %% include material islands again
            againWithMatIslands = undercutsEliminated;
            for i = 2 : length(resultsMatIslands.blobMeasurements)
                thisMatIsland = vertcat(resultsMatIslands.blobMeasurements(i).PixelList);
                for j = 1 : size(thisMatIsland, 1)
                    x = thisMatIsland(j, 2);
                    y = thisMatIsland(j, 1);
                    againWithMatIslands(x, y) = 0;
                end
            end
            %% draw vertical lines around matIslands
            [imageUndercutsBehindMatIslands] = drawVerticalLinesForUndercuts(~againWithMatIslands);
            %% detect undercuts ( = detect pores)
            [results] = calculateSurfaceParameters(app, imageUndercutsBehindMatIslands, 'pores', 'Materialinseln');
            [app.overallResults.Kennwert5] = results;
            guidata(app.UIFigure, app);
        end
        
        function  saveState(app)
            initialState_tmp = [];
            initialState_tmp.LoadImageButton.BackgroundColor = app.LoadImageButton.BackgroundColor;
            initialState_tmp.LButton_2.BackgroundColor = app.LButton_2.BackgroundColor;
            initialState_tmp.RButton.BackgroundColor = app.RButton.BackgroundColor;
            initialState_tmp.LengthScaleEditField.BackgroundColor = app.LengthScaleEditField.BackgroundColor;
            initialState_tmp.DropDown.BackgroundColor = app.DropDown.BackgroundColor;
            initialState_tmp.PrepareButton.BackgroundColor = app.PrepareButton.BackgroundColor;
            initialState_tmp.CalculateButton.BackgroundColor = app.CalculateButton.BackgroundColor;
            initialState_tmp.ExportButton.BackgroundColor = app.ExportButton.BackgroundColor;
%             datafile = fullfile(pwd,'\MyAppDefaultValues.mat');
%             save(datafile,'initialState_tmp');
            app.initialState = initialState_tmp;
        end
        
        function loadState(app)
            %                 load(pwd,'\MyAppDefaultValues.mat');
            app.LoadImageButton.BackgroundColor = app.initialState.LoadImageButton.BackgroundColor;
            app.LButton_2.BackgroundColor = app.initialState.LButton_2.BackgroundColor;
            app.RButton.BackgroundColor = app.initialState.RButton.BackgroundColor;
            app.LengthScaleEditField.BackgroundColor = app.initialState.LengthScaleEditField.BackgroundColor;
            app.DropDown.BackgroundColor = app.initialState.DropDown.BackgroundColor;
            app.PrepareButton.BackgroundColor = app.initialState.PrepareButton.BackgroundColor;
            app.CalculateButton.BackgroundColor = app.initialState.CalculateButton.BackgroundColor;
            app.ExportButton.BackgroundColor =  app.initialState.ExportButton.BackgroundColor;
            cla(app.UIAxes_2,'reset')
            cla(app.UIAxes,'reset')
            cla(app.UIAxes3_5,'reset')
            cla(app.UIAxes3_4,'reset')
            cla(app.UIAxes3_2,'reset')
            cla(app.UIAxes3,'reset')
            cla(app.UIAxes3_3,'reset')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             gitPath                 = 'C:\git\LKTSurfaceQualification';
%             LKTFunctions            = fullfile(gitPath, 'functions');
%             LKTGUIPics              = fullfile(gitPath, 'gui_pics');
%             LKTTestImages           = fullfile(gitPath, 'images');
%             addpath(fullfile(LKTFunctions))
%             addpath(fullfile(LKTFunctions, 'from2017'))
%             addpath(fullfile(LKTGUIPics))
%             addpath(fullfile(LKTTestImages))
            app.saveState;
            %% add paths to workspace
            app.currentFolder = pwd;
            app.guiImagesPath = [app.currentFolder, '\gui_pics'];
            app.exportPath = [app.currentFolder, '\export'];
            app.functionPath = [app.currentFolder, '\functions'];
            app.functionPath2017 = [app.currentFolder, '\functions\from2017'];
            app.imagePath = [app.currentFolder, '\images'];
            %% safe paths to gui (.UIFigure.paths)
            addpath(app.exportPath);
            addpath(app.guiImagesPath);
            addpath(app.functionPath);
            addpath(app.functionPath2017);
            addpath(app.imagePath);
            if strcmp(app.PlotDropDown.Value, 'Yes')
                app.doPlot = 1;
            elseif strcmp(app.PlotDropDown.Value, 'No')
                app.doPlot = 0;
            end
            
            app.HeaderPhoto.ImageSource = ([app.guiImagesPath, '\LKT_app_banner2.jpg']);
            app.FooterPhoto.ImageSource = ([app.guiImagesPath, '\LKT_app_banner_down.jpg']);
            app.icon_settings1.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_settings2.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_settings3.ImageSource = ([app.guiImagesPath, '\settings.png']);
            app.icon_folder1.ImageSource = ([app.guiImagesPath, '\folder.png']);
            app.icon_trash1.ImageSource = ([app.guiImagesPath, '\trash.png']);
            app.icon_calc1.ImageSource = ([app.guiImagesPath, '\calc.png']);
            app.icon_help.ImageSource = ([app.guiImagesPath, '\help.png']);
            
        end

        % Callback function
        function AdjustParametersButtonPushed(app, event)
            app.SelectedTab = app.SettingsTab;
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            app.TabGroup.SelectedTab = app.TabGroup2; 
        end

        % Image clicked function: icon_settings1
        function icon_settings1Clicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            %% calculate values
            set(app.DropDown, 'BackgroundColor', 'green');
            set(app.LengthScaleEditField, 'BackgroundColor', 'green');
            
            [app.imageHeight, app.imageWidth, ~] = size(app.imgOriginal);
            app.hintergrundwert =  mean2(app.imgOriginal(5:15,(round(app.imageWidth/2)-5):(round(app.imageWidth/2)+5)));
            app.materialwert = mean2(app.imgOriginal((app.imageHeight-15):(app.imageHeight-5),...
                (round(app.imageWidth/2)-5):(round(app.imageWidth/2)+5)));
            app.schwellwert = (app.hintergrundwert + app.materialwert)/2;
            img = app.imgOriginal;
            %% RGB (3 channels) convert to gray-scale
            if size(img, 3)==3
                img2 = rgb2gray(img);
            else
                img2 = img;
            end
            app.imgBW = img2 > app.schwellwert;
            if app.doPlot
                app.hfig = imtool(app.imgBW, 'InitialMagnification', app.scaleResolution);
                set(app.hfig, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '2. Schwarz-Weiß-Bild');
            end
            
            %% Massstab berechnen und Maßstab vom Bild eliminieren
            %Auslesen der Einheit des Mauestabes und setzen dieser Einheit fuer die Ausgabe
            massstablaenge = get(app.LengthScaleEditField, 'Value');
            app.MassstabEinheit = get(app.DropDown, 'Value');
            app.MassstabWert = massstablaenge/abs(app.MassstabLinksPosX - app.MassstabRechtsPosX);
            %% span a rectangle around the scale
            %% parameters
            rectRatio = 0.2;
            safetyFactor = 10;
            %% calculations
            ly = app.MassstabRechtsPosY;
            ry = app.MassstabLinksPosY;
            yBotton = mean(ly, ry) + safetyFactor;
            lx = app.MassstabRechtsPosX + safetyFactor;
            rx = app.MassstabLinksPosX - safetyFactor;
            rectWidth = abs(lx - rx);
            rectHeight = rectRatio * rectWidth;
            yTop = yBotton - rectHeight - safetyFactor;
            imgScaleDeleted = app.imgBW;
            quantZeros = nnz(~imgScaleDeleted(yTop:yBotton, rx:lx));
            quantOnes = nnz(imgScaleDeleted(yTop:yBotton, rx:lx));
            if quantOnes > quantZeros
                disp(['scale is printed in black font, on white background, ration here: ', num2str(ceil(quantZeros/quantOnes*100)), '%.'])
                imgScaleDeleted(yTop:yBotton, rx:lx) = 1;
            else
                disp(['scale is printed in white font, on black background, ration here: ', num2str(ceil(quantZeros/quantOnes*100)), '%.'])
                imgScaleDeleted(yTop:yBotton, rx:lx) = 0;
            end
            app.imgBWnoScale = imgScaleDeleted;
            if app.doPlot
                app.hfig = imtool(app.imgBWnoScale, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '3. Black/White image, scale deleted');
            end
            
            %% Erode
            app.erosionType = get(app.TypDropDown, 'Value'); % Dropdownliste in Vektor "contents" auslesen
            app.erosionValue = get(app.SizeSpinner, 'Value'); % Dropdownliste in Vektor "contents" auslesen
            se = strel(app.erosionType, app.erosionValue); % Gluettungsparameter [Form, Grueuee]
            app.imgEroded = imerode(app.imgBWnoScale, se);
            if app.doPlot
                app.hfig = imtool(app.imgEroded, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '4. Erodiertes SW-Bild');
            end
            
            app.imgErod = app.imgEroded;
            
            %% allign horizontally
            boundary = realeKontur(app.imgEroded);
            width = app.imageWidth;
            height = app.imageHeight;
            Start(1,2) = 1;
            Start(1,1) = polyval(polyfit(boundary(:,2),boundary(:,1),1),Start(1,2));
            Ende(1,2) = width;
            Ende(1,1) = polyval(polyfit(boundary(:,2),boundary(:,1),1),Ende(1,2));
            diffLR = Ende(1,1)-Start(1,1); % Unterschied der y-Komponenten
            winkel = atand(diffLR/(width/2));
            imgBWDreh = imrotate(app.imgEroded,winkel,'nearest','crop');
            imgOrigDreh = imrotate(app.imgOriginal, winkel,'nearest','crop');
            %% cut off black lines at corners. ceil: so don't cut off too less
            linkeGrenze=abs(ceil(height/2 * diffLR/(width/2)));
            if linkeGrenze <= 0
                linkeGrenze = 1;
            end
            rechteGrenze=width-floor(height/2 * diffLR/(width/2));
            if rechteGrenze >= width
                rechteGrenze = width-1;
            end
            untereGrenze = ceil(height * tan(deg2rad(winkel)));
            imgBWSchnitt = imgBWDreh(1:(height - abs(untereGrenze)), linkeGrenze:rechteGrenze);
            imgOrigSchnitt = imgOrigDreh(:,linkeGrenze:rechteGrenze);
            app.imgErod = imgBWSchnitt;
            app.imgOrig = imgOrigSchnitt;
            if app.doPlot
                app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '5. Rotated eroded image');
            end
            if app.doPlot
                app.hfig = imtool(app.imgOrig, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
            end
            
            %% usually there wouldn't be a pore now in the lower left corner.
            [height, ~, ~] = size(app.imgErod);
            for i = height : -1 : 1
                if app.imgErod(i,1) == 0
                    continue
                else
                    lastLine = i + 1;
                    break
                end
            end
            for i = 1 : width
                if app.imgErod(height,i) == 0
                    continue
                else
                    lastRow = i;
                    break
                end
            end
            app.imgErod(lastLine:height, 1:lastRow) = 1;
            
            %% if the first 40 pixels are all black, then it's surely no pore
            if nnz(app.imgErod(height,1:40)) == 0
                %% crop the image up to 'lastLine'
                imgBWSchnittSecond = imgBWSchnitt(1 : lastLine, :);
                app.imgErod = imgBWSchnittSecond;
                if app.doPlot
                    app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                    set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '5. Rotated eroded image');
                end
            end
            
            %% plot the contours
            Massstab = app.MassstabWert;
            img = app.imgErod;
            boundary = realeKontur(img);
            [heightBoundary,~] = size(boundary);
            [height,width,~] = size(img);
            
            %Laenge der realen Kontur ausrechnen
            laengeOberfl=0;
            for i =2:heightBoundary
                laengeOberfl = laengeOberfl + sqrt((boundary(i,1)-boundary(i-1,1))^2 + (boundary(i,2)-boundary(i-1,2))^2);
            end
            app.realeKonturY = boundary(:,1);
            app.realeKonturX = boundary(:,2);
            disp(['real contour length: ', num2str(round(laengeOberfl*Massstab)), ' µm / ', ...
                num2str(laengeOberfl), ' px.']);
            disp(['picture width: ', num2str(round(width*Massstab)), ' µm / ', ...
                num2str(width), ' px.']);
            disp(['ratio: ', num2str(round(laengeOberfl/width, 1))]);
            
            count = 0;
            k = 0;
            iZeile = 0;
            %Bestimmung der Oberflaeche durch "Aufsicht"
            zWerte = zeros(width*5,2);
            for i = 1:(width*5)
                iZeile = i + count;
                zWerte(iZeile,2) = i;
                for j= 1:height
                    if img(j,i) == 1
                        zWerte(iZeile,1) = j;
                        % ab dem 2. Pixel vergleichen, ob ein Hinterschnitt
                        % vorliegen kuennte (also die Hoehendifferenz grueueer als 1
                        % ist)
                        if i > 1
                            % Hoehenwert des aktuellen Pixel
                            a = zWerte(iZeile,1);
                            % Hoehenwert des vorherigen Pixel
                            b = zWerte(iZeile-1,1);
                            % wenn die Hoehendifferenz > 1 ist
                            if abs(b - a) > 1
                                %zusuetzlich den vorherigen Pixel schreiben (gleiche
                                % x-Koordinate wie vorheriger Pixel, gleiche
                                % y-Koordinate wie aktueller Pixel
                                % vorheriger Pixel: Hoehe: zWerte(i-1,1), Breite: zWerte(i-1,2)
                                % aktueller Pixel: Hoehe: zWerte(i,1), Breite: zWerte(i,2)
                                % zukuenftiger Pixel: Hoehe: zWerte(i+1,1), Breite: zWerte(i+1,2)
                                
                                % aktueller Pixel wird zu i+1, damit der
                                % zwischengeschobene Pixel i sein kann
                                zWerte(iZeile+1,1) = j;
                                zWerte(iZeile+1,2) = i;
                                zWerte(iZeile,2) = zWerte(iZeile-1,2);
                                zWerte(iZeile,1) = j;
                                
                                
                                % i-Zaehler um 1 erHoehen, da ein zusuetzliches
                                % Pixel in die Liste aufgenommen wurde. Ansonsten
                                % wuerde man beim nuechsten Durchlauf den letzten
                                % Pixel ueberschreiben.
                                count = count + 1;
                            end
                        end
                        break;
                    end
                end
                % Wenn Bildbreite erreicht, For-Schleife beenden
                if iZeile - count >= width - 1
                    break
                end
            end
            
            zWerte = [zWerte((1:iZeile),1),zWerte((1:iZeile),2)];
            app.aufKontur = zWerte;
            Mittel = mean2(app.realeKonturY); % Bild bereits ausgerichtet, deshalb Mittelwertberechnung nun trivial
            
            if app.doPlot
                app.hfig = imtool(app.imgErod, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
                hold on; % Ansonsten wuerde das Bild schon fertig pruesentiert werden!
                plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
                plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2);
                plot([1 width],[Mittel Mittel],'b','Linewidth',2);
                hold off; % erst jetzt werden die Linien tatsuechlich ueber das Bild gelegt
                
                app.hfig = imtool(app.imgOrig, 'InitialMagnification', app.scaleResolution);
                set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '6. Rotated original image');
                hold on; % Ansonsten wuerde das Bild schon fertig pruesentiert werden!
                plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
                plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2);
                plot([1 width],[Mittel Mittel],'b','Linewidth',2);
                hold off; % erst jetzt werden die Linien tatsuechlich ueber das Bild gelegt
            end
            
            imshow(app.imgOrig, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'on')
            plot(app.realeKonturX, app.realeKonturY, 'g', 'Linewidth', 2, 'Parent', app.UIAxes_2); % g: gruen; Linewidth: 2 Pixel (breite Linie)
            plot(app.aufKontur(:,2), app.aufKontur(:,1),'r','Linewidth',2, 'Parent', app.UIAxes_2);
            plot([1 width],[Mittel Mittel],'b','Linewidth', 2, 'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'off')
            set(app.PrepareButton, 'BackgroundColor', 'green');
            
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            %             imshow(app.plots.plot1, 'Parent', app.UIAxes_2);
            %             [FileName, PathName]  = uigetfile( ...
            %                 {[app.workingDirectory, '/*.jpg;*.jpeg;*.png;*.tif;*.tiff;*.psd'],...
            %                 'picture Files (*.jpg,*.jpeg,*.png,*.tif,*.tiff,*.psd)';
            %                 [app.workingDirectory, '/*.jpg;*.jpeg'],  'jpg files (*.jpg,*.jpeg)'; ...
            %                 [app.workingDirectory, '/*.png'],  'png files (*.png)'; ...
            %                 [app.workingDirectory, '/*.*'],  'All Files (*.*)'}, 'Bitte wuehlen Sie ein Mikroskopiebild aus');
            [FileName, PathName]  = uigetfile(  ...
                {'*.jpg;*.jpeg;*.png;*.tif;*.tiff;*.psd',...
                'picture Files (*.jpg,*.jpeg,*.png,*.tif,*.tiff,*.psd)';
                '*.jpg;*.jpeg',  'jpg files (*.jpg,*.jpeg)'; ...
                '*.png',  'png files (*.png)'; ...
                '*.*',  'All Files (*.*)'}, 'Bitte wählen Sie ein Mikroskopiebild aus');
            filename = [PathName, FileName];
            [pathstr, name, ext] = fileparts(filename);
            if isequal(FileName,0)
                disp('User selected Cancel')
            else
                disp(['User selected ', filename])
            end
            app.BildPfad = [pathstr '\'];
            app.BildName = name;
            app.BildNamePlusExtention = [name, ext];
            %% open new figure with the loaded image
            imgPath = [app.BildPfad app.BildNamePlusExtention]; %Strings werden aneinandergehuengt
            app.imgOriginal = imread(imgPath);
            [height, width,~] = size(app.imgOriginal);
            %% calculate display proportations to make figure best fit
            Pix_SS = get(0, 'screensize');
            ScreenHeight = Pix_SS(1,4);
            ScreenWidth = Pix_SS(1,3);
            FaktorHeight = height / ScreenHeight;
            FaktorWidth = width / ScreenWidth;
            if FaktorHeight > FaktorWidth
                PossibleFigureHeight = ScreenHeight - 100; %ausgemessen - so ist es an meinem PC
                app.scaleResolution = floor(PossibleFigureHeight / height*100) -1;
            else
                app.scaleResolution = (ScreenWidth / width*100)-0.1;
            end
            app.hfig = imtool(app.imgOriginal, 'InitialMagnification', app.scaleResolution);
            set(gcf, 'WindowState', 'maximized', 'InvertHardCopy', 'off', 'Name', '1. Originalbild');
            
            %% plot figure into GUI
            imshow(app.imgOriginal, 'Parent', app.UIAxes);
            set(app.LoadImageButton, 'BackgroundColor', 'green');
            guidata(app.LoadImageButton, app);
            
        end

        % Callback function
        function LButtonPushed(app, event)
            
            set(app.LButton, 'BackgroundColor', 'yellow');
            dcm_obj = datacursormode(app.hfig);
            if get(hObject,'Value')==1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
            
                %Position auslesen
                pos = info_struct.Position;
                posX = pos(1,1);
                posY = pos(1,2);
                handles.MassstabLinksPosX = posX;
                handles.MassstabLinksPosY = posY;
            end
            
            set(app.LButton, 'BackgroundColor', 'green');
        end

        % Value changed function: LButton_2
        function LButton_2ValueChanged(app, event)
            value = app.LButton_2.Value;
            set(app.LButton_2, 'BackgroundColor','yellow');
            dcm_obj = datacursormode(app.hfig);
            if value == 1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
                app.MassstabLinksPosX = info_struct.Position(1,1);
                app.MassstabLinksPosY = info_struct.Position(1,2);
                guidata(app.LButton_2, app);
            end
            set(app.LButton_2, 'BackgroundColor', 'green');
        end

        % Value changed function: RButton
        function RButtonValueChanged(app, event)
            value = app.RButton.Value;
            set(app.RButton, 'BackgroundColor','yellow');
            dcm_obj = datacursormode(app.hfig);
            if value == 1
                set(dcm_obj,'Enable','on');
                uiwait;
            else
                uiresume;
                set(dcm_obj,'Enable','off');
                info_struct = getCursorInfo(dcm_obj);
                app.MassstabRechtsPosX = info_struct.Position(1,1);
                app.MassstabRechtsPosY = info_struct.Position(1,2);
                guidata(app.RButton, app);
            end
            set(app.RButton, 'BackgroundColor', 'green');
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            runAllQualityParametersWithGivenParameters(app)
        end

        % Value changed function: PlotDropDown
        function PlotDropDownValueChanged(app, event)
            value = app.PlotDropDown.Value;
            if strcmp(value, 'Yes')
                app.doPlot = 1;
            elseif strcmp(value, 'No')
                app.doPlot = 0;
            end
        end

        % Image clicked function: icon_trash1
        function icon_trash1ImageClicked(app, event)
            kill2
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            app.workingDirectory = uigetdir(path);
            app.WorkingDirEditField.Value = app.workingDirectory;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.savingDirectory = uigetdir(path);
            app.SpeicherortEditField.Value = app.savingDirectory;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.FastModeTab;
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            app.loadState;
        end

        % Image clicked function: icon_settings2
        function icon_settings2ImageClicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
        end

        % Image clicked function: icon_settings3
        function icon_settings3ImageClicked(app, event)
            app.TabGroup.SelectedTab = app.SettingsTab;
        end

        % Size changed function: FastModeTab
        function FastModeTabSizeChanged(app, event)
            position = app.FastModeTab.Position;
            
        end

        % Image clicked function: icon_folder1
        function icon_folder1ImageClicked(app, event)
            eval(['!explorer /e,' cd])
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProgrammMenu = uimenu(app.UIFigure);
            app.ProgrammMenu.Text = 'Programm';
            app.OpenMenu = uimenu(app.ProgrammMenu);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProgrammMenu);
            app.SaveMenu.Text = 'Save';
            app.ExportMenu = uimenu(app.ProgrammMenu);
            app.ExportMenu.Text = 'Export';
            app.OpenInputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenInputFolderMenu.Text = 'Open Input Folder';
            app.OpenOutputFolderMenu = uimenu(app.ProgrammMenu);
            app.OpenOutputFolderMenu.Text = 'Open Output Folder';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.PreferencesMenu = uimenu(app.EditMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FastModeTab = uitab(app.TabGroup);
            app.FastModeTab.SizeChangedFcn = createCallbackFcn(app, @FastModeTabSizeChanged, true);
            app.FastModeTab.Title = 'Fast Mode';
            app.LoadImageButton = uibutton(app.FastModeTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.PrepareButton = uibutton(app.FastModeTab, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.UIAxes_2 = uiaxes(app.FastModeTab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes = uiaxes(app.FastModeTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.CalculateButton = uibutton(app.FastModeTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.icon_settings1 = uiimage(app.FastModeTab);
            app.icon_settings1.ImageClickedFcn = createCallbackFcn(app, @icon_settings1Clicked, true);
            app.icon_settings1.ImageSource = 'settings.png';
            app.icon_folder1 = uiimage(app.FastModeTab);
            app.icon_folder1.ImageClickedFcn = createCallbackFcn(app, @icon_folder1ImageClicked, true);
            app.icon_folder1.ImageSource = 'folder.png';
            app.icon_trash1 = uiimage(app.FastModeTab);
            app.icon_trash1.ImageClickedFcn = createCallbackFcn(app, @icon_trash1ImageClicked, true);
            app.icon_trash1.ImageSource = 'trash.png';
            app.icon_calc1 = uiimage(app.FastModeTab);
            app.icon_calc1.ImageSource = 'calc.png';
            app.icon_settings2 = uiimage(app.FastModeTab);
            app.icon_settings2.ImageClickedFcn = createCallbackFcn(app, @icon_settings2ImageClicked, true);
            app.icon_settings2.ImageSource = 'settings.png';
            app.icon_settings3 = uiimage(app.FastModeTab);
            app.icon_settings3.ImageClickedFcn = createCallbackFcn(app, @icon_settings3ImageClicked, true);
            app.icon_settings3.ImageSource = 'settings.png';
            app.icon_help = uiimage(app.FastModeTab);
            app.icon_help.ImageSource = 'help.png';
            app.ScaleLabel = uilabel(app.FastModeTab);
            app.ScaleLabel.Text = 'Scale';
            app.LButton_2 = uibutton(app.FastModeTab, 'state');
            app.LButton_2.ValueChangedFcn = createCallbackFcn(app, @LButton_2ValueChanged, true);
            app.LButton_2.Text = 'L';
            app.RButton = uibutton(app.FastModeTab, 'state');
            app.RButton.ValueChangedFcn = createCallbackFcn(app, @RButtonValueChanged, true);
            app.RButton.Text = 'R';
            app.LengthScaleEditField = uieditfield(app.FastModeTab, 'numeric');
            app.LengthScaleEditField.Value = 50;
            app.DropDown = uidropdown(app.FastModeTab);
            app.DropDown.Items = {'mm', 'µm', 'nm'};
            app.DropDown.Value = 'µm';
            app.ExportButton = uibutton(app.FastModeTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.Image2 = uiimage(app.FastModeTab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'FAVPNG_time-machine-airport-time-capsule-macos-backup-apple_4XGXYXSm.png';
            app.SingleModeTab = uitab(app.TabGroup);
            app.SingleModeTab.Title = 'Single Mode';
            app.UIAxes2 = uiaxes(app.SingleModeTab);
            title(app.UIAxes2, 'Result Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.MaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.MaterialIslandsButton.Text = '1. Material Islands';
            app.UndercutsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsButton.Text = '2. Undercuts';
            app.PoresButton = uibutton(app.SingleModeTab, 'push');
            app.PoresButton.Text = '3. Pores';
            app.RoughnessButton = uibutton(app.SingleModeTab, 'push');
            app.RoughnessButton.Text = '4. Roughness';
            app.UndercutsbehindMaterialIslandsButton = uibutton(app.SingleModeTab, 'push');
            app.UndercutsbehindMaterialIslandsButton.Text = '5. Undercuts behind Material Islands';
            app.StartmanuallyLabel = uilabel(app.SingleModeTab);
            app.StartmanuallyLabel.Text = 'Start manually';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.ErosionTab = uitab(app.TabGroup2);
            app.ErosionTab.Title = 'Erosion';
            app.SizeSpinnerLabel = uilabel(app.ErosionTab);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.ErosionTab);
            app.SizeSpinner.Value = 2;
            app.TypDropDownLabel = uilabel(app.ErosionTab);
            app.TypDropDownLabel.Text = 'Typ';
            app.TypDropDown = uidropdown(app.ErosionTab);
            app.TypDropDown.Items = {'diamond', 'disk', 'octagon', 'square'};
            app.TypDropDown.Value = 'diamond';
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.ImageTypeDropDownLabel = uilabel(app.ImageTab);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.ImageTab);
            app.ImageTypeDropDown.Items = {'b/w image', 'Original image'};
            app.ImageTypeDropDown.Value = 'b/w image';
            app.PlotDropDownLabel = uilabel(app.ImageTab);
            app.PlotDropDownLabel.Text = 'Plot';
            app.PlotDropDown = uidropdown(app.ImageTab);
            app.PlotDropDown.Items = {'Yes', 'No'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotDropDownValueChanged, true);
            app.PlotDropDown.Value = 'No';
            app.ParametersTab = uitab(app.TabGroup2);
            app.ParametersTab.Title = 'Parameters';
            app.TabGroup3 = uitabgroup(app.ParametersTab);
            app.MatIslandsTab = uitab(app.TabGroup3);
            app.MatIslandsTab.Title = '1. MatIslands';
            app.UndercutsTab = uitab(app.TabGroup3);
            app.UndercutsTab.Title = '2. Undercuts';
            app.PoresTab = uitab(app.TabGroup3);
            app.PoresTab.Title = '3. Pores';
            app.RoughnessTab = uitab(app.TabGroup3);
            app.RoughnessTab.Title = '4. Roughness';
            app.Label = uilabel(app.RoughnessTab);
            app.Label.Text = '';
            app.NumberofsectionsalongpicturewidthEditFieldLabel = uilabel(app.RoughnessTab);
            app.NumberofsectionsalongpicturewidthEditFieldLabel.Text = 'Number of sections along picturewidth: ';
            app.NumberofsectionsalongpicturewidthEditField = uieditfield(app.RoughnessTab, 'numeric');
            app.NumberofsectionsalongpicturewidthEditField.Value = 50;
            app.SpacebehindMatIslandsTab = uitab(app.TabGroup3);
            app.SpacebehindMatIslandsTab.Title = '5. Space behind MatIslands';
            app.PathsTab = uitab(app.TabGroup2);
            app.PathsTab.Title = 'Paths';
            app.BrowseButton = uibutton(app.PathsTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SpeicherortLabel = uilabel(app.PathsTab);
            app.SpeicherortLabel.Text = 'Speicherort:';
            app.SpeicherortEditField = uieditfield(app.PathsTab, 'text');
            app.SpeicherortEditField.Value = 'C:\Users\micha\Desktop\Oberflaechenrauheit-LKT\V09_final';
            app.BrowseButton_3 = uibutton(app.PathsTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.WorkingDirEditFieldLabel = uilabel(app.PathsTab);
            app.WorkingDirEditFieldLabel.Text = 'Working Dir:';
            app.WorkingDirEditField = uieditfield(app.PathsTab, 'text');
            app.WorkingDirEditField.Value = 'C:\Users\micha\Desktop\Oberflaechenrauheit-LKT\V09_final';
            app.ApplyButton = uibutton(app.SettingsTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.icon_help_2 = uiimage(app.SettingsTab);
            app.icon_help_2.ImageSource = 'help.png';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.TabGroup3_2 = uitabgroup(app.ResultsTab);
            app.MatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.MatIslandsTab_2.Title = '1. MatIslands';
            app.UIAxes3 = uiaxes(app.MatIslandsTab_2);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UndercutsTab_2 = uitab(app.TabGroup3_2);
            app.UndercutsTab_2.Title = '2. Undercuts';
            app.UIAxes3_2 = uiaxes(app.UndercutsTab_2);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.PoresTab_2 = uitab(app.TabGroup3_2);
            app.PoresTab_2.Title = '3. Pores';
            app.UIAxes3_3 = uiaxes(app.PoresTab_2);
            title(app.UIAxes3_3, '')
            xlabel(app.UIAxes3_3, '')
            ylabel(app.UIAxes3_3, '')
            app.RoughnessTab_2 = uitab(app.TabGroup3_2);
            app.RoughnessTab_2.Title = '4. Roughness';
            app.UIAxes3_4 = uiaxes(app.RoughnessTab_2);
            title(app.UIAxes3_4, '')
            xlabel(app.UIAxes3_4, '')
            ylabel(app.UIAxes3_4, '')
            app.SpacebehindMatIslandsTab_2 = uitab(app.TabGroup3_2);
            app.SpacebehindMatIslandsTab_2.Title = '5. Space behind MatIslands';
            app.UIAxes3_5 = uiaxes(app.SpacebehindMatIslandsTab_2);
            title(app.UIAxes3_5, '')
            xlabel(app.UIAxes3_5, '')
            ylabel(app.UIAxes3_5, '')
            app.HeaderPhoto = uiimage(app.UIFigure);
            app.HeaderPhoto.ImageSource = 'LKT_app_banner2.jpg';
            app.FooterPhoto = uiimage(app.UIFigure);
            app.FooterPhoto.ImageSource = 'LKT_app_banner_down.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLS_Oberflaechenrauheit_final_App_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end